classdef MATLABapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        ConnectButton          matlab.ui.control.Button
        COMPORTSpinnerLabel    matlab.ui.control.Label
        COMPORTSpinner         matlab.ui.control.Spinner
        StartCollectionButton  matlab.ui.control.Button
        StopCollectionButton   matlab.ui.control.Button
        DisconnectButton       matlab.ui.control.Button
    end


    properties (Access = private)
        obj % the serial port object
    end


    methods (Access = private)

        % Button pushed function: StartCollectionButton
        function StartCollectionButtonPushed(app, event)
            fprintf(app.obj,'START\r')
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            port = app.COMPORTSpinner.Value; % Get the COM Port Value
            portStr = strcat('COM',int2str(app.port));
            app.obj = serial(portStr,'BaudRate',115200);
            fopen(app.obj)
        end

        % Button pushed function: StopCollectionButton
        function StopCollectionButtonPushed(app, event)
            fprintf(app.obj,'STOP\r')
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            fclose(app.obj);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Accelerometer Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'EDA Data')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'PPG Data')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.COMPORTSpinnerLabel = uilabel(app.UIFigure);
            app.COMPORTSpinnerLabel.Text = 'COM PORT';
            app.COMPORTSpinner = uispinner(app.UIFigure);
            app.COMPORTSpinner.Value = 8;
            app.StartCollectionButton = uibutton(app.UIFigure, 'push');
            app.StartCollectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartCollectionButtonPushed, true);
            app.StartCollectionButton.Text = 'Start Collection';
            app.StopCollectionButton = uibutton(app.UIFigure, 'push');
            app.StopCollectionButton.ButtonPushedFcn = createCallbackFcn(app, @StopCollectionButtonPushed, true);
            app.StopCollectionButton.Text = 'Stop Collection';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MATLABapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end